classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        File         matlab.ui.container.Menu
        open         matlab.ui.container.Menu
        save         matlab.ui.container.Menu
        transform    matlab.ui.container.Menu
        rotate       matlab.ui.container.Menu
        Move         matlab.ui.container.Menu
        Menu_17      matlab.ui.container.Menu
        Menu_18      matlab.ui.container.Menu
        Menu_19      matlab.ui.container.Menu
        RGB2gray     matlab.ui.container.Menu
        Menu_21      matlab.ui.container.Menu
        Menu_22      matlab.ui.container.Menu
        noise        matlab.ui.container.Menu
        Menu_15      matlab.ui.container.Menu
        avgFilter    matlab.ui.container.Menu
        saltPepper   matlab.ui.container.Menu
        gaussian     matlab.ui.container.Menu
        Menu_20      matlab.ui.container.Menu
        SobelMenu    matlab.ui.container.Menu
        robertsMenu  matlab.ui.container.Menu
        prewittMenu  matlab.ui.container.Menu
        ImageAxes    matlab.ui.control.UIAxes
        GreenAxes    matlab.ui.control.UIAxes
        BlueAxes     matlab.ui.control.UIAxes
        RedAxes      matlab.ui.control.UIAxes
        ImageAxes_2  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image % the Processing Image
    end
    
    methods (Access = private)
        
%         function updateImage(app,imagefile)
%             im = imread(imagefile);
%             %imagesc(app.ImageAxes,im);
%             
%             switch size(im,3)
%                 case 1
%                     % Display the grayscale image
%                     imagesc(app.ImageAxes,im);
%                     
%                 case 3
%                     % Display the truecolor image
%                     imagesc(app.ImageAxes,im);
%                     
%                 otherwise
%                     % Error when image is not grayscale or truecolor
%                     uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
%                     return;
%             end
%             
%         end
        
        function updateImage(app,imagefile)
            
                try
                    app.Image = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            
            % Create histograms based on number of color channels
            switch size(app.Image,3)
                case 1
                    % Display the grayscale image
                    %imagesc(app.ImageAxes,im);
                    imshow(app.Image,'Parent',app.ImageAxes);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, app.Image, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, app.Image, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, app.Image, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    %imagesc(app.ImageAxes,im);
                    imshow(app.Image,'Parent',app.ImageAxes);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, app.Image(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, app.Image(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, app.Image(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    %uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    warndlg('图片格式错误。','ERROR');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    
%         function saveImage(app,filepath)
%             app.save(filepath,'data');
%             imwrite(app.Image,'app1.png');
%         end
        
        function d = midfilt(~, x, n)
            [M, N] = size(x);
            x1 = x;
            x2 = x1;
            for i = 1:M-n+1
                for j = 1:N-n+1
                    c = x1(i:i+n-1,j:j+n-1);
                    e = c(1, :);
                    for k = 2:n
                        e = [e, c(k, :)];
                    end
                    x2 (i+(n-1)/2, j+(n-1)/2) = median(e);
                end
            end
            d = x2;
        end
        
        function d = avg_filter(~, x, n)
            a(1:n,1:n) = 1;
            [height, width] = size(x);
            x1 = double(x);
            x2 = x1;
            for i = 1:height-n+1
                for j=1:width-n+1
                    c=x1(i:i+(n-1),j:j+(n-1)).*a;
                    s=sum(sum(c));
                    x2(i+(n-1)/2,j+(n-1)/2)=s/(n*n);
                end
            end
            d = uint8(x2);
        end
        
        
        function d = saltPepperNoise(~,I)
            switch size(I,3)
                case 1
                    % Display the grayscale image
                    [width,height]=size(I);
                    result2 = I;
                    k1=0.1;
                    k2=0.1;
                    a1=rand(width,height)<k1;
                    a2=rand(width,height)<k2;
                    t1=result2(:,:,1);
                    t1(a1&a2)=0;
                    t1(a1& ~a2)=255;
                    result2(:,:,1)=t1;
                    d = result2;
                case 3
                    % the truecolor image
                    [width, height,~] = size(I);
                    result2 = I;
                    k1 = 0.1;
                    k2 = 0.1;
                    a1 = rand(width,height) < k1;
                    a2 = rand(width,height) < k2;
                    t1 = result2(:,:,1);
                    t2 = result2(:,:,2);
                    t3 = result2(:,:,3);
                    t1(a1&a2) = 0;
                    t2(a1&a2) = 0;
                    t3(a1&a2) = 0;
                    t1(a1& ~a2)=255;
                    t2(a1& ~a2)=255;
                    t3(a1& ~a2)=255;
                    result2(:,:,1)=t1;
                    result2(:,:,2)=t2;
                    result2(:,:,3)=t3;
                    d = result2;
                otherwise
                    % Error when image is not grayscale or truecolor
                    %uialert(app.UIFigure, '图片格式错误。', 'ERROR');
                    warndlg('图片格式错误。','ERROR');
                    d = f;
                    return;
            end
        end
        
        function d = gaussianNoise(~,I)
            [m,n,~]=size(I);
            y=0+0.1*randn(m,n);%二维高斯分布矩阵 0是均值 0.1是标准差
            %先将其double化，再除以255 便于后面计算
            K=double(I)/255;
            %加上噪声
            K=K+y;
            %将像素范围扩大至0--255
            K=K*255;
            %转换为uint8类型
            d=uint8(K);
        end
        
        function g = CustomRotate(~, f, angle)
            [h,w,~]=size(f);
            radian=angle/180*pi;
            cosa=cos(radian);
            sina=sin(radian);
            w2=round(abs(cosa)*w+h*abs(sina));
            h2=round(abs(cosa)*h+w*abs(sina));
            g=uint8(zeros(h2,w2,3));
            for x=1:w2
                for y=1:h2
                    x0=uint32(x*cosa+y*sina-0.5*w2*cosa-0.5*h2*sina+0.5*w);
                    y0=uint32(y*cosa-x*sina+0.5*w2*sina-0.5*h2*cosa+0.5*h);
        
                    x0=round(x0);
                    y0=round(y0);
                    if x0>0 && y0>0 && w>=x0 && h>=y0
                        g(y,x,:)=f(y0,x0,:);
                    end
                end
            end
        end
        
        function g = CustomRGB2gray(~,f)
            n = double(f);
            
            switch size(f,3)
                case 1
                    % Display the grayscale image
                    warndlg('请使用RGB图像进行灰度处理。','WARNING');
                    g = f;
                case 3
                    % the truecolor image
                    [x, y, ~] = size(f);
                    R = n(:,:,1);
                    G = n(:,:,2);
                    B = n(:,:,3);
                    g = zeros(x,y);
                    for i = 1 : x
                        for j = 1 : y
                            g(i,j)=R(i,j)*0.2125+G(i,j)*0.7154+B(i,j)*0.0721;
                        end
                    end
                    g = uint8(g);
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    %uialert(app.UIFigure, '图片格式错误。', 'ERROR');
                    warndlg('图片格式错误。','ERROR');
                    g = f;
                    return;
            end
        end
        
        function g = CustomReverse(~,f)
            [M, N] = size(f);
            I = f;
            for i = 1 : M
                for j = 1 : N
                    I(i, j) = f(i, N - j + 1);
                end
            end
            g = I;
        end
        
        function g = CustomReverseY(~, f)
            [M, N] = size(f);
            I = f;
            for i = 1 : M
                for j = 1 : N
                    I(i, j) = f(M - i + 1, j);
                end
            end
            g = I;
        end


        function g = CustomMove(~,f,delX,delY)
            [R, C] = size(f);
            res = zeros(R, C);
            trans = [1 0 delX; 0 1 delY; 0 0 1];
            for i = 1 : R
                for j = 1 : C
                    temp = [i; j; 1];
                    temp = trans * temp;
                    x = temp(1, 1);
                    y = temp(2, 1);
                    if (x <= R) && (y <= C) && (x >= 1) && (y >= 1)
                        res(x, y) = f(i, j);
                    end
                end
            end
            %g = uint8(res);
            g = res;
        end
        
        function output = my_edge(~,input_img,method)
            if size(input_img,3)==3
                input_img=rgb2gray(input_img);
            end

            input_img=im2double(input_img);
            sobel_x=[-1,-2,-1;0,0,0;1,2,1];
            sobel_y=[-1,0,1;-2,0,2;-1,0,1];
            prewitt_x=[-1,-1,-1;0,0,0;1,1,1];
            prewitt_y=[-1,0,1;-1,0,1;-1,0,1];

            psf=fspecial('gaussian',[5,5],1);
            input_img=imfilter(input_img,psf);%高斯低通滤波，平滑图像,但可能会使图像丢失细节
            input_img=medfilt2(input_img); %中值滤波消除孤立点
            [m,n]=size(input_img);
            output=zeros(m,n);
            if nargin==3
                if strcmp(method,'sobel')
                    for i=2:m-1
                        for j=2:n-1
                            local_img=input_img(i-1:i+1, j-1:j+1);
            %近似边缘检测，加快速度    %output(i,j)=abs(sum(sum(sobel_x.*local_img)))+abs(sum(sum(sobel_x.*local_img)));
                            output(i,j)=sqrt(sum(sum(sobel_x.*local_img))^2+sum(sum(sobel_y.*local_img))^2);
                        end
                    end
                elseif strcmp(method,'prewitt')
                    for i=2:m-1
                        for j=2:n-1
                            local_img=input_img(i-1:i+1, j-1:j+1);
                            output(i,j)=sqrt(sum(sum(prewitt_x.*local_img))^2+sum(sum(prewitt_y.*local_img))^2);
                        end
                    end

                else%如果不输入算子的名称，默认使用roberts算子进行边缘检测
                    for i=1:m-1
                        for j=1:n-1
                            output(i,j)=abs(input_img(i,j)-input_img(i+1,j+1))+ ...
                            abs(input_img(i+1,j)-input_img(i,j+1));
                        end
                    end    
                end

                output=imadjust(output);%使边缘图像更明显
                thresh=graythresh(output);%确定二值化阈值
                output=bwmorph(imbinarize(output,thresh),'thin',inf);%强化细节
            end
        end
        
%         function g = hist(~,I)
%             J = rgb2gray(I);
%             graydis = zeros(1,256);
%             [h w] = size(J);
%             for x = 1 : h
%                 for y = 1 : w
%                     graydis(1)
%                 end
%             end
%         end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: open
        function openFile(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateImage(app, fname);
            end
        end

        % Menu selected function: save
        function saveFile(app, event)
            %unsavedImage = app.Image;
            %imwrite(unsavedImage,"app1.jpg","jpg");
            
            [file,path] = uiputfile({'*.jpg'; ...
                '*.tif'; ...
                '*.png'; ...
                '*.gif'}, ...
                'Save as');
            
            fullFilePath = fullfile(path,file);
            imwrite(app.Image,fullFilePath);
        end

        % Menu selected function: Menu_15
        function midfiltMenu(app, event)
            pImage = app.Image;
            pImage = midfilt(app,pImage,3);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: saltPepper
        function saltPepperMenu(app, event)
            pImage = app.Image;
            pImage = saltPepperNoise(app,pImage);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: gaussian
        function gaussianMenu(app, event)
            pImage = app.Image;
            pImage = gaussianNoise(app,pImage);
            %todo: unfinished parameter input
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: avgFilter
        function avgFilterMenuSelected(app, event)
            pImage = app.Image;
            pImage = avg_filter(app,pImage,3);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: rotate
        function rotateMenuSelected(app, event)
            an = inputdlg('Input the rotation angle of the picture');
            an = str2double(an);
            pImage = app.Image;
            pImage = CustomRotate(app,pImage,an);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: RGB2gray
        function RGB2grayMenuSelected(app, event)
            pImage = app.Image;
            pImage = CustomRGB2gray(app,pImage);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: Move
        function MoveMenuSelected(app, event)
            prompt = {'X轴平移距离：','Y轴平移距离'};
            dlg_title = 'Input';
            num_lines = 1;
            def = {'20','20'};
            an = inputdlg(prompt,dlg_title,num_lines,def);
            
            disX = str2double(an(1,1));
            disY = str2double(an(2,1));
            
            pImage = app.Image;
            pImage = CustomMove(app,pImage,disX,disY);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: Menu_19
        function Menu_19Selected(app, event)
            pImage = app.Image;
            pImage = CustomReverse(app,pImage);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: Menu_18
        function Menu_18Selected(app, event)
            pImage = app.Image;
            pImage = CustomReverseY(app,pImage);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
            pImage = app.Image;
            pImage = my_edge(app,pImage,'sobel');
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: robertsMenu
        function robertsMenuSelected(app, event)
            pImage = app.Image;
            pImage = my_edge(app,pImage,'');
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end

        % Menu selected function: prewittMenu
        function prewittMenuSelected(app, event)
            pImage = app.Image;
            pImage = my_edge(app,pImage,'prewitt');
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = '文件';
            app.open = uimenu(app.File);
            app.open.MenuSelectedFcn = createCallbackFcn(app, @openFile, true);
            app.open.Text = '打开图片';
            app.save = uimenu(app.File);
            app.save.MenuSelectedFcn = createCallbackFcn(app, @saveFile, true);
            app.save.Text = '保存图片';
            app.transform = uimenu(app.UIFigure);
            app.transform.Text = '变换';
            app.rotate = uimenu(app.transform);
            app.rotate.MenuSelectedFcn = createCallbackFcn(app, @rotateMenuSelected, true);
            app.rotate.Text = '图像旋转';
            app.Move = uimenu(app.transform);
            app.Move.MenuSelectedFcn = createCallbackFcn(app, @MoveMenuSelected, true);
            app.Move.Text = '图像平移';
            app.Menu_17 = uimenu(app.transform);
            app.Menu_17.Text = '图像翻转';
            app.Menu_18 = uimenu(app.Menu_17);
            app.Menu_18.MenuSelectedFcn = createCallbackFcn(app, @Menu_18Selected, true);
            app.Menu_18.Text = '垂直翻转';
            app.Menu_19 = uimenu(app.Menu_17);
            app.Menu_19.MenuSelectedFcn = createCallbackFcn(app, @Menu_19Selected, true);
            app.Menu_19.Text = '水平翻转';
            app.RGB2gray = uimenu(app.transform);
            app.RGB2gray.MenuSelectedFcn = createCallbackFcn(app, @RGB2grayMenuSelected, true);
            app.RGB2gray.Text = '灰度变换';
            app.Menu_21 = uimenu(app.UIFigure);
            app.Menu_21.Text = '直方图';
            app.Menu_22 = uimenu(app.Menu_21);
            app.Menu_22.Text = '显示直方图';
            app.noise = uimenu(app.UIFigure);
            app.noise.Text = '噪声';
            app.Menu_15 = uimenu(app.noise);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @midfiltMenu, true);
            app.Menu_15.Text = '中值滤波';
            app.avgFilter = uimenu(app.noise);
            app.avgFilter.MenuSelectedFcn = createCallbackFcn(app, @avgFilterMenuSelected, true);
            app.avgFilter.Text = '均值滤波';
            app.saltPepper = uimenu(app.noise);
            app.saltPepper.MenuSelectedFcn = createCallbackFcn(app, @saltPepperMenu, true);
            app.saltPepper.Text = '椒盐噪声';
            app.gaussian = uimenu(app.noise);
            app.gaussian.MenuSelectedFcn = createCallbackFcn(app, @gaussianMenu, true);
            app.gaussian.Text = '高斯噪声';
            app.Menu_20 = uimenu(app.UIFigure);
            app.Menu_20.Text = '边缘';
            app.SobelMenu = uimenu(app.Menu_20);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel算子';
            app.robertsMenu = uimenu(app.Menu_20);
            app.robertsMenu.MenuSelectedFcn = createCallbackFcn(app, @robertsMenuSelected, true);
            app.robertsMenu.Text = 'roberts算子';
            app.prewittMenu = uimenu(app.Menu_20);
            app.prewittMenu.MenuSelectedFcn = createCallbackFcn(app, @prewittMenuSelected, true);
            app.prewittMenu.Text = 'prewitt算子';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end