classdef demo_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        File       matlab.ui.container.Menu
        open       matlab.ui.container.Menu
        save       matlab.ui.container.Menu
        Menu_3     matlab.ui.container.Menu
        Menu_6     matlab.ui.container.Menu
        Menu_8     matlab.ui.container.Menu
        Menu_4     matlab.ui.container.Menu
        Menu_7     matlab.ui.container.Menu
        Menu_10    matlab.ui.container.Menu
        OtsuMenu   matlab.ui.container.Menu
        Menu_12    matlab.ui.container.Menu
        Menu_11    matlab.ui.container.Menu
        Menu_13    matlab.ui.container.Menu
        Menu_5     matlab.ui.container.Menu
        Menu_9     matlab.ui.container.Menu
        Menu2      matlab.ui.container.Menu
        noise      matlab.ui.container.Menu
        Menu_15    matlab.ui.container.Menu
        Button     matlab.ui.control.Button
        Button_2   matlab.ui.control.Button
        Button_3   matlab.ui.control.Button
        Button_4   matlab.ui.control.Button
        Button_5   matlab.ui.control.Button
        Button_6   matlab.ui.control.Button
        Button_7   matlab.ui.control.Button
        Button_8   matlab.ui.control.Button
        Button_9   matlab.ui.control.Button
        Button_10  matlab.ui.control.Button
        Button_11  matlab.ui.control.Button
        Button_12  matlab.ui.control.Button
        Button_13  matlab.ui.control.Button
        Button_14  matlab.ui.control.Button
        Button_15  matlab.ui.control.Button
        ImageAxes  matlab.ui.control.UIAxes
        GreenAxes  matlab.ui.control.UIAxes
        BlueAxes   matlab.ui.control.UIAxes
        RedAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image % the Processing Image
    end
    
    methods (Access = private)
        
%         function updateImage(app,imagefile)
%             im = imread(imagefile);
%             %imagesc(app.ImageAxes,im);
%             
%             switch size(im,3)
%                 case 1
%                     % Display the grayscale image
%                     imagesc(app.ImageAxes,im);
%                     
%                 case 3
%                     % Display the truecolor image
%                     imagesc(app.ImageAxes,im);
%                     
%                 otherwise
%                     % Error when image is not grayscale or truecolor
%                     uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
%                     return;
%             end
%             
%         end
        
        function updateImage(app,imagefile)
            
                try
                    app.Image = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
            
            % Create histograms based on number of color channels
            switch size(app.Image,3)
                case 1
                    % Display the grayscale image
                    %imagesc(app.ImageAxes,im);
                    imshow(app.Image,'Parent',app.ImageAxes);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, app.Image, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, app.Image, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, app.Image, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    %imagesc(app.ImageAxes,im);
                    imshow(app.Image,'Parent',app.ImageAxes);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, app.Image(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, app.Image(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, app.Image(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    
%         function saveImage(app,filepath)
%             app.save(filepath,'data');
%             imwrite(app.Image,'app1.png');
%         end
        
        function d = midfilt(~, x, n)
            [M, N] = size(x);
            x1 = x;
            x2 = x1;
            for i = 1:M-n+1
                for j = 1:N-n+1
                    c = x1(i:i+n-1,j:j+n-1);
                    e = c(1, :);
                    for k = 2:n
                        e = [e, c(k, :)];
                    end
                    x2 (i+(n-1)/2, j+(n-1)/2) = median(e);
                end
            end
            d = x2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: open
        function openFile(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateImage(app, fname);
            end
        end

        % Menu selected function: save
        function saveFile(app, event)
%             [filename,path] = uiputfile('*.mat','Save file name','ufilename');
%             filepath=strcat(path,filename);

%             folder_name = uigetdir();
            
            unsavedImage = app.Image;
%             save('file.mat',"unsavedImage");
            imwrite(unsavedImage,"app1.jpg","jpg");
            
            %todo : unfinished save file method
        end

        % Menu selected function: Menu_15
        function midfiltMenu(app, event)
            pImage = app.Image;
            pImage = midfilt(app,pImage,3);
            app.Image = pImage;
            imshow(app.Image,'Parent',app.ImageAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = '文件';
            app.open = uimenu(app.File);
            app.open.MenuSelectedFcn = createCallbackFcn(app, @openFile, true);
            app.open.Text = '打开图片';
            app.save = uimenu(app.File);
            app.save.MenuSelectedFcn = createCallbackFcn(app, @saveFile, true);
            app.save.Text = '保存图片';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '编辑';
            app.Menu_6 = uimenu(app.Menu_3);
            app.Menu_6.Text = '撤销';
            app.Menu_8 = uimenu(app.Menu_3);
            app.Menu_8.Text = '重做';
            app.Menu_4 = uimenu(app.UIFigure);
            app.Menu_4.Text = '图像';
            app.Menu_7 = uimenu(app.Menu_4);
            app.Menu_7.Text = '阈值处理';
            app.Menu_10 = uimenu(app.Menu_7);
            app.Menu_10.Text = '基本全局阈值';
            app.OtsuMenu = uimenu(app.Menu_7);
            app.OtsuMenu.Text = 'Otsu方法';
            app.Menu_12 = uimenu(app.Menu_7);
            app.Menu_12.Text = '基于边缘信息';
            app.Menu_11 = uimenu(app.Menu_12);
            app.Menu_11.Text = '梯度';
            app.Menu_13 = uimenu(app.Menu_12);
            app.Menu_13.Text = '拉普拉斯算子';
            app.Menu_5 = uimenu(app.UIFigure);
            app.Menu_5.Text = '滤镜';
            app.Menu_9 = uimenu(app.Menu_5);
            app.Menu_9.Text = 'Menu';
            app.Menu2 = uimenu(app.Menu_5);
            app.Menu2.Text = 'Menu2';
            app.noise = uimenu(app.UIFigure);
            app.noise.Text = '噪声';
            app.Menu_15 = uimenu(app.noise);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @midfiltMenu, true);
            app.Menu_15.Text = '中值滤波';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = '指针.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = '画笔.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = '画笔.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Icon = '桶.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Icon = '指针.png';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Icon = '指针.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Icon = '画笔.png';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Icon = '画笔.png';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Icon = '桶.png';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Icon = '指针.png';
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Icon = '指针.png';
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Icon = '画笔.png';
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.Icon = '画笔.png';
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.Icon = '桶.png';
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.Icon = '指针.png';
            app.Button_15.Text = '';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end