classdef tabTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TabGroup     matlab.ui.container.TabGroup
        Tab          matlab.ui.container.Tab
        GridLayout   matlab.ui.container.GridLayout
        Button       matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        DropDown     matlab.ui.control.DropDown
        Tab3         matlab.ui.container.Tab
        GridLayout2  matlab.ui.container.GridLayout
        Button7      matlab.ui.control.Button
        Button8      matlab.ui.control.Button
        Tab2         matlab.ui.container.Tab
        ImageAxes    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Image % the Processing Image
    end
    
    methods (Access = private)
        
        function updateImage(app,imagefile)
                try
                    app.Image = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateImage(app, fname);
            end
            imshow(app.Image,'Parent',app.ImageAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout = uigridlayout(app.Tab);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '打开';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.Text = '保存';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.Text = '图像旋转';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.Text = '图像翻转';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'水平翻转', '垂直翻转'};
            app.DropDown.Value = '水平翻转';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.GridLayout2 = uigridlayout(app.Tab3);
            app.Button7 = uibutton(app.GridLayout2, 'push');
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.GridLayout2, 'push');
            app.Button8.Text = 'Button8';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tabTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end